classdef absorcion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PORTADA_TAB                     matlab.ui.container.Tab
        TORREDEABSORCINLabel            matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        SharonSimkinLabel               matlab.ui.control.Label
        HedymarOrtizLabel               matlab.ui.control.Label
        NathanContrerasLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        INSTRUCCIONES_TAB               matlab.ui.container.Tab
        HTML                            matlab.ui.control.HTML
        Titulo_Instrucciones            matlab.ui.control.Label
        DATOS_TAB                       matlab.ui.container.Tab
        mLabel                          matlab.ui.control.Label
        AlturadeempaqueLabel            matlab.ui.control.Label
        LminLabel_2                     matlab.ui.control.Label
        atmLabel                        matlab.ui.control.Label
        LminLabel                       matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        Slider_Presion                  matlab.ui.control.Slider
        PresinSliderLabel               matlab.ui.control.Label
        Etiqueta_Caudal_Gas             matlab.ui.control.Label
        Etiqueta_Caudal_Liquido         matlab.ui.control.Label
        Etiqueta_Temperatura            matlab.ui.control.Label
        Knob_Caudal_Gas                 matlab.ui.control.Knob
        Knob_Caudal_Liquido             matlab.ui.control.Knob
        Knob_Temperatura                matlab.ui.control.Knob
        Edit_Altura                     matlab.ui.control.NumericEditField
        Boton_Calculos_Teoricos         matlab.ui.control.Button
        Condiciones_operacion           matlab.ui.container.Panel
        atmLabel_2                      matlab.ui.control.Label
        display_Presion                 matlab.ui.control.NumericEditField
        CAUDALDEGASLabel_2              matlab.ui.control.Label
        display_temperatura             matlab.ui.control.NumericEditField
        display_liquido                 matlab.ui.control.NumericEditField
        display_gas                     matlab.ui.control.NumericEditField
        CAUDALDEGASLabel                matlab.ui.control.Label
        CAUDALDELIQUIDOLabel            matlab.ui.control.Label
        TEMPERATURACEditFieldLabel_2    matlab.ui.control.Label
        Seccion_Empaquetado             matlab.ui.container.ButtonGroup
        DimetroLabel                    matlab.ui.control.Label
        Drop_Down_Empaquetado           matlab.ui.control.DropDown
        Drop_Down_Diametros             matlab.ui.control.DropDown
        TabGroupEquilibrio              matlab.ui.container.TabGroup
        EquilibrioTab                   matlab.ui.container.Tab
        Display_Constante_Henry         matlab.ui.control.NumericEditField
        Display_moles_equilibrio        matlab.ui.control.NumericEditField
        MolesenelEquilibriomolminLabel  matlab.ui.control.Label
        ConstantedeHenrykPaLabel        matlab.ui.control.Label
        TabGroupTransferencia           matlab.ui.container.Tab
        TextokLa                        matlab.ui.control.NumericEditField
        TextoMolesTransferencia         matlab.ui.control.NumericEditField
        molestericosabsorbidosLabel     matlab.ui.control.Label
        TextoPercetAbs                  matlab.ui.control.NumericEditField
        deAbsorcinTericoEditFieldLabel  matlab.ui.control.Label
        kLa1sLabel                      matlab.ui.control.Label
        Panel_DatosTitulacion           matlab.ui.container.Panel
        DatosTitulacion                 matlab.ui.control.Table
        AgregarFila_Titulacion          matlab.ui.control.Button
        Procesartitulacion              matlab.ui.control.Button
        Vol_Alicuota                    matlab.ui.control.NumericEditField
        Concen_NaOH                     matlab.ui.control.NumericEditField
        ConcentracinNaOHMEditFieldLabel  matlab.ui.control.Label
        VolumenalicuotamLEditFieldLabel  matlab.ui.control.Label
        Titulo_Datos_Teoricos_Experimentales  matlab.ui.control.Label
        RESULTADOSTab                   matlab.ui.container.Tab
        ResultadosTitulacion            matlab.ui.control.Table
        ResultadosTitulacinPanel        matlab.ui.container.Panel
        MolesAbsEditField_2Label_3      matlab.ui.control.Label
        Display_Moles_Totales           matlab.ui.control.NumericEditField
        MolesAbsEditField_2Label_2      matlab.ui.control.Label
        MolesAbsTitu                    matlab.ui.control.NumericEditField
        PercentAbsTitu                  matlab.ui.control.NumericEditField
        MolesAbsLabel                   matlab.ui.control.Label
        Display_eficiencia              matlab.ui.control.NumericEditField
        ExportaraExcelButton            matlab.ui.control.Button
        deAbsLabel                      matlab.ui.control.Label
        RESULTADOSLabel                 matlab.ui.control.Label
        GraficoAbsorcionTitulacion      matlab.ui.control.UIAxes
        GraficoMolesTitulacion          matlab.ui.control.UIAxes
        FLUJOSTab                       matlab.ui.container.Tab
        Titulo_Datos_Teoricos_Experimentales_2  matlab.ui.control.Label
        Edit_Fraccion_EL                matlab.ui.control.NumericEditField
        FraccinMolarCO2Label_2          matlab.ui.control.Label
        Edit_EL                         matlab.ui.control.NumericEditField
        FlujoLinmolminLabel             matlab.ui.control.Label
        Edit_Fraccion_SL                matlab.ui.control.NumericEditField
        FraccinMolarCO2Label            matlab.ui.control.Label
        Edit_SL                         matlab.ui.control.NumericEditField
        FlujoLoutmolminLabel            matlab.ui.control.Label
        Edit_Fraccion_EG                matlab.ui.control.NumericEditField
        FraccinMolarCO2Label_4          matlab.ui.control.Label
        Edit_EG                         matlab.ui.control.NumericEditField
        FlujoGinmolminLabel             matlab.ui.control.Label
        Edit_Fraccion_SG                matlab.ui.control.NumericEditField
        FraccinMolarCO2Label_3          matlab.ui.control.Label
        Edit_SG                         matlab.ui.control.NumericEditField
        FlujoGoutmolminLabel            matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
    end

    methods (Access = private)
        
        function actualizarDiagramaFlujos(app)
            moles_in_gas_co2 = app.Display_Moles_Totales.Value;
            moles_abs_co2 = app.MolesAbsTitu.Value;
            Q_agua_Lmin = app.display_liquido.Value;
            T_C = app.display_temperatura.Value;
            P_total_atm = app.display_Presion.Value;
            
            conc_inicial_co2 = app.ResultadosTitulacion.Data{1, 1};
            if isempty(conc_inicial_co2) || isnan(conc_inicial_co2)
                conc_inicial_co2 = 0;
            end
            
            % REF. NIST: Jones, F. E., & Harris, G. L. (1992). ITS-90 density of water formulation for volumetric standards calibration. Journal of Research of the National Institute of Standards and Technology, 97(3), 335-340. DOI: 10.6028/jres.097.013
            a0 = 999.83952;
            a1 = 16.945176;
            a2 = -7.9870401e-3;
            a3 = -46.170461e-6;
            a4 = 105.56302e-9;
            a5 = -280.54253e-12;
            b = 16.89785e-3;
            
            rho_agua = (a0 + a1*T_C + a2*T_C^2 + a3*T_C^3 + a4*T_C^4 + a5*T_C^5) / (1 + b*T_C); %Aqui Usamos la ecuacion de Patterson y Morris
            PM_agua = 18.01528;
            moles_agua_por_litro = rho_agua / PM_agua;
            
            x_in = conc_inicial_co2 / (conc_inicial_co2 + moles_agua_por_litro);
            L_in_total = Q_agua_Lmin * (moles_agua_por_litro + conc_inicial_co2);
            
            y_in = 1.0;
            G_in_total = moles_in_gas_co2 / y_in;
            
            A = 8.07131; 
            B = 1730.63; 
            C = 233.426;
            P_sat_mmHg = 10^(A - (B / (T_C + C)));
            P_sat_atm = P_sat_mmHg / 760;
            y_agua_out = P_sat_atm / P_total_atm;
            
            moles_out_gas_co2 = moles_in_gas_co2 - moles_abs_co2;
            
            if (1 - y_agua_out) > 0
                G_out_total = moles_out_gas_co2 / (1 - y_agua_out);
            else
                G_out_total = moles_out_gas_co2;
            end
            
            moles_agua_evaporada = G_out_total * y_agua_out;
            
            if G_out_total > 0
                y_out = moles_out_gas_co2 / G_out_total;
            else
                y_out = 0;
            end
            
            L_in_agua_pura = L_in_total * (1 - x_in);
            L_out_agua_pura = L_in_agua_pura - moles_agua_evaporada;
            moles_co2_salida_liq = (L_in_total * x_in) + moles_abs_co2;
            
            L_out_total = L_out_agua_pura + moles_co2_salida_liq;
            
            if L_out_total > 0
                x_out = moles_co2_salida_liq / L_out_total;
            else
                x_out = 0;
            end
            
            app.Edit_EL.Value = L_in_total;
            app.Edit_Fraccion_EL.Value = x_in;
            app.Edit_EG.Value = G_in_total;
            app.Edit_Fraccion_EG.Value = y_in;
            app.Edit_SL.Value = L_out_total;
            app.Edit_Fraccion_SL.Value = x_out;
            app.Edit_SG.Value = G_out_total;
            app.Edit_Fraccion_SG.Value = y_out;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DatosTitulacion.ColumnName = {'Tiempo (min)', 'Volumen de NaOH (mL)', 'Temperatura (°C)'};
            app.DatosTitulacion.ColumnEditable = [true, true, true]; 
            app.DatosTitulacion.Data = cell(14,3);
            
            app.ResultadosTitulacion.ColumnName = {'Concentración CO2 (mol/L)', 'Flujo Absorbido (mol/min)', '% Recuperación'};
            app.ResultadosTitulacion.ColumnEditable = [false, false, false]; 
            app.ResultadosTitulacion.Data = cell(14, 3);

            app.display_liquido.Limits = app.Knob_Caudal_Liquido.Limits;
            app.display_liquido.Value = app.Knob_Caudal_Liquido.Value;
            
            app.display_gas.Limits = app.Knob_Caudal_Gas.Limits;
            app.display_gas.Value = app.Knob_Caudal_Gas.Value;
            
            app.display_temperatura.Limits = app.Knob_Temperatura.Limits;
            app.display_temperatura.Value = app.Knob_Temperatura.Value;
            
            app.display_Presion.Limits = app.Slider_Presion.Limits;
            app.display_Presion.Value = app.Slider_Presion.Value;

            app.TabGroup.SelectedTab = app.PORTADA_TAB;
        end

        % Value changed function: Drop_Down_Empaquetado
        function Drop_Down_EmpaquetadoValueChanged(app, event)
            seleccion = app.Drop_Down_Empaquetado.Value;
            
            if strcmp(seleccion, 'Anillos de Raschig')
                app.Drop_Down_Diametros.Items = {'2 pulg.', '1 1/2 pulg.', '1 pulg.', '5/8 pulg.', '1/2 pulg.', '3/8 pulg.'}; 
                app.Drop_Down_Diametros.Value = '1 pulg.'; % Valor por defecto
                app.Drop_Down_Diametros.Enable = true;
                
            elseif strcmp(seleccion, 'Sillas de Montar')
                app.Drop_Down_Diametros.Items = {'1 1/2 pulg.', '1 pulg.', '1/2 pulg.'}; 
                app.Drop_Down_Diametros.Value = '1 pulg.'; % Valor por defecto
                app.Drop_Down_Diametros.Enable = true; 
                
            elseif strcmp(seleccion, 'Predeterminado')
                app.Drop_Down_Diametros.Items = {'N/A'};
                app.Drop_Down_Diametros.Value = 'N/A';
                app.Drop_Down_Diametros.Enable = false;
            end
            app.Boton_Calculos_Teoricos.Enable = true;
        end

        % Button pushed function: Boton_Calculos_Teoricos
        function Boton_Calculos_TeoricosButtonPushed(app, event)
            D_torre = 0.1524; % [m]
            Z_torre = app.Edit_Altura.Value; % [m]
            Area_torre = pi * (D_torre/2)^2;
            tipo_empaque = app.Drop_Down_Empaquetado.Value; 
            diametro = app.Drop_Down_Diametros.Value;
            alpha = 80;  
            n = 0.22;    
            
            if strcmp(tipo_empaque, 'Anillos de Raschig')
                switch diametro
                    case '2 pulg.'
                        alpha = 80; n = 0.22;
                    case '1 1/2 pulg.'
                        alpha = 90; n = 0.22;
                    case '1 pulg.'
                        alpha = 100; n = 0.22;
                    case '5/8 pulg.'
                        alpha = 150; n = 0.28; 
                    case '1/2 pulg.'
                        alpha = 280; n = 0.35;
                    case '3/8 pulg.'
                        alpha = 550; n = 0.46;
                end
                
            elseif strcmp(tipo_empaque, 'Sillas de Montar')
                switch diametro
                    case '1 1/2 pulg.'
                        alpha = 160; n = 0.28;
                    case '1 pulg.'
                        alpha = 170; n = 0.28;
                    case '1/2 pulg.'
                        alpha = 150; n = 0.28;
                end
            end
            
            T_celsius = app.display_temperatura.Value; 
            T_K = T_celsius + 273.15; 
            P_atm = app.display_Presion.Value; 
            
            a0 = 999.83952;
            a1 = 16.945176;
            a2 = -7.9870401e-3;
            a3 = -46.170461e-6;
            a4 = 105.56302e-9;
            a5 = -280.54253e-12;
            b = 16.89785e-3;
            rho_agua = (a0 + a1*T_celsius + a2*T_celsius^2 + a3*T_celsius^3 + a4*T_celsius^4 + a5*T_celsius^5) / (1 + b*T_celsius);
            
            mu_agua = 2.414e-5 * 10^(247.8 / (T_K - 140));
            D_AB = 1.77e-9 * (T_K / 293.15) * (993e-6 / mu_agua);
            
            kH_298 = 0.034; %IMPORTANTE: Aqui se calcula la constante de Henry (Ref: https://webbook.nist.gov/cgi/cbook.cgi?ID=C124389&Mask=10)
            dlnH_dT = 2400;
            kH_T = kH_298 * exp(dlnH_dT * ((1/T_K) - (1/298.15)));
            Ca_star = (P_atm * 1.01325) * kH_T * (rho_agua/1000); 
            
            Q_H2O_Lmin = app.display_liquido.Value; 
            L_mass_flux = ((Q_H2O_Lmin*60/1000) * rho_agua * 2.20462) / (Area_torre * 10.7639);
            mu_lb_ft_h = mu_agua * 2419.09;
            rho_lb_ft3 = rho_agua * 0.062428;
            D_AB_ft2_h = D_AB * 38750.1;
            kLa_h = alpha * D_AB_ft2_h * (L_mass_flux / mu_lb_ft_h)^(1 - n) * (mu_lb_ft_h / (rho_lb_ft3 * D_AB_ft2_h))^0.5;
            kLa_s = kLa_h / 3600;
            v_liq = (Q_H2O_Lmin / 60 / 1000) / Area_torre;
            
            C_a_L_teorica = Ca_star * (1 - exp(-kLa_s * Z_torre / v_liq));
            porcentaje_abs_teo = (C_a_L_teorica / Ca_star) * 100;
            
            app.TextokLa.Value = kLa_s; 
            app.Display_Constante_Henry.Value = kH_T;
            app.Display_moles_equilibrio.Value = Ca_star;
            app.TextoMolesTransferencia.Value = C_a_L_teorica; 
            app.TextoPercetAbs.Value = porcentaje_abs_teo;
        end

        % Button pushed function: Procesartitulacion
        function ProcesartitulacionButtonPushed(app, event)
            datos = app.DatosTitulacion.Data; 
            
            tiempo = cell2mat(cellfun(@(x) double(string(x)), datos(:,1), 'UniformOutput', false));
            Vol_NaOH = cell2mat(cellfun(@(x) double(string(x)), datos(:,2), 'UniformOutput', false));
            T_exp = cell2mat(cellfun(@(x) double(string(x)), datos(:,3), 'UniformOutput', false));
            validRows = ~isnan(tiempo) & ~isnan(Vol_NaOH) & ~isnan(T_exp);
            tiempo = tiempo(validRows);
            Vol_NaOH = Vol_NaOH(validRows);
            T_exp = T_exp(validRows);
            
            if isempty(tiempo)
                uialert(app.UIFigure, 'Por favor, ingrese todos los datos numéricos en la tabla.', 'Error de Datos');
                return;
            end
            
            L = app.display_liquido.Value; 
            P_atm = app.display_Presion.Value; 
            C_NaOH = app.Concen_NaOH.Value; 
            V_alicuota = app.Vol_Alicuota.Value;
            
            respuestas = zeros(length(Vol_NaOH), 3);
  
            for i = 1:length(Vol_NaOH) % REF. Harned, H. S., & Davis, R. (1943). The Ionization Constant of Carbonic Acid in Water and the Solubility of Carbon Dioxide in Water and Estimate of the Kinetic Constant at 0 to 50°. Journal of the American Chemical Society, 65(10), 2030-2037.
                
                Co_calculado = fzero(@(Co)concentracion(Co, Vol_NaOH(i), C_NaOH, T_exp(i), V_alicuota), 1e-3);
                
                respuestas(i,1) = Co_calculado; 
                respuestas(i,2) = Co_calculado * L; 
                
                T_K = T_exp(i) + 273.15;
                
                kH_298 = 0.034;
                dlnH_dT = 2400;
                kH_T = kH_298 * exp(dlnH_dT * ((1/T_K) - (1/298.15)));
                
                a0 = 999.83952;
                a1 = 16.945176;
                a2 = -7.9870401e-3;
                a3 = -46.170461e-6;
                a4 = 105.56302e-9;
                a5 = -280.54253e-12;
                b = 16.89785e-3;
                rho_agua = (a0 + a1*T_exp(i) + a2*T_exp(i)^2 + a3*T_exp(i)^3 + a4*T_exp(i)^4 + a5*T_exp(i)^5) / (1 + b*T_exp(i));
                
                C_eq = (P_atm * 1.01325) * kH_T * (rho_agua/1000);
                
                respuestas(i,3) = (Co_calculado / C_eq) * 100;
            end
             
            app.ResultadosTitulacion.Data = cell(12, 3);
            app.ResultadosTitulacion.Data(1:length(tiempo), :) = num2cell(respuestas);
            
            app.MolesAbsTitu.Value = max(respuestas(:,2));  
            app.PercentAbsTitu.Value = max(respuestas(:,3)); 
             
            color = [0.96, 0.37, 0.38];
            color2 = [0, 0.4470, 0.7410];
            
            cla(app.GraficoMolesTitulacion);
            hold(app.GraficoMolesTitulacion, 'on');
            plot(app.GraficoMolesTitulacion, tiempo, respuestas(:,2), '-o', 'Color', color, 'LineWidth', 2, 'MarkerFaceColor', color);
            xlabel(app.GraficoMolesTitulacion, 'Tiempo (min)');
            ylabel(app.GraficoMolesTitulacion, 'Flujo CO2 (mol/min)');
            title(app.GraficoMolesTitulacion, 'Flujo de CO2 Absorbido');
            grid(app.GraficoMolesTitulacion, 'on');
            hold(app.GraficoMolesTitulacion, 'off');
              
            cla(app.GraficoAbsorcionTitulacion);
            hold(app.GraficoAbsorcionTitulacion, 'on');
            plot(app.GraficoAbsorcionTitulacion, tiempo, respuestas(:,3), '-^', 'Color', color2, 'LineWidth', 2, 'MarkerFaceColor', color2);
            xlabel(app.GraficoAbsorcionTitulacion, 'Tiempo (min)');
            ylabel(app.GraficoAbsorcionTitulacion, '% de Absorción');
            title(app.GraficoAbsorcionTitulacion, 'Rendimiento de Absorción');
            ylim(app.GraficoAbsorcionTitulacion, [0 100]); 
            grid(app.GraficoAbsorcionTitulacion, 'on');
            hold(app.GraficoAbsorcionTitulacion, 'off');
            
            function cero = concentracion(Co, V_NaOH, C_NaOH, T_celsius, V_alic)
                T_kelvin = T_celsius + 273.15;  
                V_NaOH_L = V_NaOH / 1000;
                mol_NaOH = C_NaOH * V_NaOH_L;
                mol_HCO3 = mol_NaOH;
                V_alic_L = V_alic / 1000;
                
                x = mol_HCO3 / (V_alic_L + V_NaOH_L); 
                pK1 = 3404.71 / T_kelvin + 0.032786 * T_kelvin - 14.8435; 
                K1 = 10^(-pK1);
                cero = K1 - (x^2) / (Co - x); 
            end

            % REF. Pitzer para gases no polares: Smith, J. M., Van Ness, H. C., & Abbott, M. M. (2007)
            Tc_CO2 = 304.12;    
            Pc_CO2 = 7374;      
            omega_CO2 = 0.224;  
            T_K = app.display_temperatura.Value + 273.15; 
            P_kPa = 200;        
            R_gas = 8.31446;    
            Flujo_Gas_Lmin = app.display_gas.Value; 
            Tr = T_K / Tc_CO2;
            Pr = P_kPa / Pc_CO2;
 
            B0 = 0.083 - (0.422 / (Tr^1.6));
            B1 = 0.139 - (0.172 / (Tr^4.2));
  
            Z = 1 + (B0 * Pr / Tr) + (omega_CO2 * B1 * Pr / Tr);
            moles_entrada_totales = (P_kPa * Flujo_Gas_Lmin) / (Z * R_gas * T_K);
            app.Display_Moles_Totales.Value = moles_entrada_totales;
  
            if ~isempty(app.MolesAbsTitu.Value) && app.MolesAbsTitu.Value > 0
                moles_absorbidos = app.MolesAbsTitu.Value;
                eficiencia_real = (moles_absorbidos / moles_entrada_totales) * 100;
                
                app.Display_eficiencia.Value = eficiencia_real;
            else
                app.Display_eficiencia.Value = 0;
            end
            
            app.actualizarDiagramaFlujos();
        end

        % Value changed function: Concen_NaOH
        function Concen_NaOHValueChanged(app, event)
            C_NaOH = app.Concen_NaOH.Value;
            V_alicuota = app.Vol_Alicuota.Value; 
            app.Procesartitulacion.Enable = (C_NaOH > 0) && (V_alicuota > 0);
        end

        % Button pushed function: AgregarFila_Titulacion
        function AgregarFila_TitulacionButtonPushed(app, event)
            datosActuales = app.DatosTitulacion.Data;
            nuevaFila = cell(1, 3); 
            app.DatosTitulacion.Data = [datosActuales; nuevaFila];
        end

        % Value changing function: Knob_Temperatura
        function Knob_TemperaturaValueChanging(app, event)
            app.display_temperatura.Value = event.Value;
            app.Boton_Calculos_Teoricos.Enable = true;
        end

        % Value changed function: display_temperatura
        function display_temperaturaValueChanged(app, event)
            app.Knob_Temperatura.Value = app.display_temperatura.Value;
            app.Boton_Calculos_Teoricos.Enable = true;
        end

        % Value changing function: Knob_Caudal_Liquido
        function Knob_Caudal_LiquidoValueChanging(app, event)
            app.display_liquido.Value = event.Value;
            app.Boton_Calculos_Teoricos.Enable = true;
        end

        % Value changed function: display_liquido
        function display_liquidoValueChanged(app, event)
            app.Knob_Caudal_Liquido.Value = app.display_liquido.Value;
            app.Boton_Calculos_Teoricos.Enable = true;
        end

        % Value changing function: Knob_Caudal_Gas
        function Knob_Caudal_GasValueChanging(app, event)
            app.display_gas.Value = event.Value;
            app.Boton_Calculos_Teoricos.Enable = true;
        end

        % Value changed function: display_gas
        function display_gasValueChanged(app, event)
            app.Knob_Caudal_Gas.Value = app.display_gas.Value;
            app.Boton_Calculos_Teoricos.Enable = true;
        end

        % Value changing function: Slider_Presion
        function Slider_PresionValueChanging(app, event)
            app.display_Presion.Value = event.Value;
            app.Boton_Calculos_Teoricos.Enable = true;
        end

        % Value changed function: display_Presion
        function display_PresionValueChanged(app, event)
            app.Slider_Presion.Value = app.display_Presion.Value;
            app.Boton_Calculos_Teoricos.Enable = true;
        end

        % Button pushed function: ExportaraExcelButton
        function ExportaraExcelButtonPushed(app, event)
            [archivo, ruta] = uiputfile('*.xlsx', 'Guardar Reporte de Absorcion');
            if isequal(archivo, 0) || isequal(ruta, 0)
                return;
            end
            
            nombre_completo = fullfile(ruta, archivo);
            
            if exist(nombre_completo, 'file')
                fid = fopen(nombre_completo, 'a');
                if fid == -1
                    uialert(app.UIFigure, 'El archivo de Excel está abierto. Por favor, ciérrelo e intente nuevamente.', 'Archivo en uso', 'Icon', 'warning');
                    return;
                else
                    fclose(fid);
                end
            end
            
            Parametros = {
                'PARÁMETRO', 'VALOR', 'UNIDAD';
                'Caudal de Liquido', app.display_liquido.Value, 'L/min';
                'Caudal de Gas', app.display_gas.Value, 'L/min';
                'Temperatura', app.display_temperatura.Value, '°C';
                'Presion', app.display_Presion.Value, 'atm';
                'Altura de Empaque', app.Edit_Altura.Value, 'm';
                'Tipo de Empaque', app.Drop_Down_Empaquetado.Value, '-';
                'Diametro Empaque', app.Drop_Down_Diametros.Value, '-'
            };
            
            Resultados_Teo = {
                'RESULTADO TEORICO', 'VALOR', 'UNIDAD';
                'Constante de Henry (kH)', app.Display_Constante_Henry.Value, 'mol/L*atm';
                'Conc. Equilibrio (Ca*)', app.Display_moles_equilibrio.Value, 'mol/L';
                'Coef. Transferencia (kLa)', app.TextokLa.Value, '1/s';
                'Absorcion Teorica', app.TextoMolesTransferencia.Value, 'mol/L';
                'Eficiencia Teorica', app.TextoPercetAbs.Value, '%'
            };

            Resultados_Exp = {
                'BALANCE DE MASA', 'VALOR / FLUJO', 'UNIDAD', 'FRACCIÓN MOLAR (x, y)';
                'Eficiencia Real', app.Display_eficiencia.Value, '%', '';
                'Moles CO2 Absorbidos', app.MolesAbsTitu.Value, 'mol/min', '';
                'Entrada Liquido (EL)', app.Edit_EL.Value, 'mol/min', app.Edit_Fraccion_EL.Value;
                'Entrada Gas (EG)', app.Edit_EG.Value, 'mol/min', app.Edit_Fraccion_EG.Value;
                'Salida Liquido (SL)', app.Edit_SL.Value, 'mol/min', app.Edit_Fraccion_SL.Value;
                'Salida Gas (SG)', app.Edit_SG.Value, 'mol/min', app.Edit_Fraccion_SG.Value
            };
            
            Data_Lab = app.DatosTitulacion.Data;
            Headers_Lab = app.DatosTitulacion.ColumnName';
            Tabla_Lab = [Headers_Lab; Data_Lab];
            
            try
                writecell(Parametros, nombre_completo, 'Sheet', 'Reporte', 'Range', 'A1');
                writecell(Resultados_Teo, nombre_completo, 'Sheet', 'Reporte', 'Range', 'A10');
                writecell(Resultados_Exp, nombre_completo, 'Sheet', 'Reporte', 'Range', 'A18');
                writecell({'DATOS DE LABORATORIO'}, nombre_completo, 'Sheet', 'Reporte', 'Range', 'A27');
                writecell(Tabla_Lab, nombre_completo, 'Sheet', 'Reporte', 'Range', 'A28');
                uialert(app.UIFigure, '¡Datos exportados con éxito!', 'Éxito', 'Icon', 'success');
                
            catch ME
                uialert(app.UIFigure, ['Error crítico al exportar: ' ME.message], 'Error', 'Icon', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PORTADA_TAB = uitab(app.TabGroup);
            app.PORTADA_TAB.Title = 'PORTADA';
            app.Image = uiimage(app.PORTADA_TAB);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'portada.png');
            app.NathanContrerasLabel = uilabel(app.PORTADA_TAB);
            app.NathanContrerasLabel.Text = 'Nathan Contreras';
            app.HedymarOrtizLabel = uilabel(app.PORTADA_TAB);
            app.HedymarOrtizLabel.Text = 'Hedymar Ortiz';
            app.SharonSimkinLabel = uilabel(app.PORTADA_TAB);
            app.SharonSimkinLabel.Text = 'Sharon Simkin';
            app.Image3 = uiimage(app.PORTADA_TAB);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'logo_unimet.png');
            app.TORREDEABSORCINLabel = uilabel(app.PORTADA_TAB);
            app.TORREDEABSORCINLabel.Text = 'TORRE DE ABSORCIÓN';
            app.INSTRUCCIONES_TAB = uitab(app.TabGroup);
            app.INSTRUCCIONES_TAB.Title = 'INSTRUCCIONES';
            app.Titulo_Instrucciones = uilabel(app.INSTRUCCIONES_TAB);
            app.Titulo_Instrucciones.Text = 'INSTRUCCIONES';
            app.HTML = uihtml(app.INSTRUCCIONES_TAB);
            app.HTML.HTMLSource = 'instrucciones.html';
            app.DATOS_TAB = uitab(app.TabGroup);
            app.DATOS_TAB.Title = 'DATOS TEÓRICOS Y EXPERIMENTALES';
            app.Titulo_Datos_Teoricos_Experimentales = uilabel(app.DATOS_TAB);
            app.Titulo_Datos_Teoricos_Experimentales.Text = 'DATOS TEÓRICOS Y EXPERIMENTALES';
            app.Panel_DatosTitulacion = uipanel(app.DATOS_TAB);
            app.Panel_DatosTitulacion.Title = 'DATOS DE LA TITULACIÓN';
            app.VolumenalicuotamLEditFieldLabel = uilabel(app.Panel_DatosTitulacion);
            app.VolumenalicuotamLEditFieldLabel.Text = 'Volumen alicuota (mL)';
            app.ConcentracinNaOHMEditFieldLabel = uilabel(app.Panel_DatosTitulacion);
            app.ConcentracinNaOHMEditFieldLabel.Text = 'Concentración NaOH (M)';
            app.Concen_NaOH = uieditfield(app.Panel_DatosTitulacion, 'numeric');
            app.Concen_NaOH.Limits = [0 Inf];
            app.Concen_NaOH.ValueChangedFcn = createCallbackFcn(app, @Concen_NaOHValueChanged, true);
            app.Vol_Alicuota = uieditfield(app.Panel_DatosTitulacion, 'numeric');
            app.Vol_Alicuota.Limits = [0 Inf];
            app.Procesartitulacion = uibutton(app.Panel_DatosTitulacion, 'push');
            app.Procesartitulacion.ButtonPushedFcn = createCallbackFcn(app, @ProcesartitulacionButtonPushed, true);
            app.Procesartitulacion.Enable = 'off';
            app.Procesartitulacion.Text = 'Procesar titulación';
            app.AgregarFila_Titulacion = uibutton(app.Panel_DatosTitulacion, 'push');
            app.AgregarFila_Titulacion.ButtonPushedFcn = createCallbackFcn(app, @AgregarFila_TitulacionButtonPushed, true);
            app.AgregarFila_Titulacion.Text = 'Agregar Fila';
            app.DatosTitulacion = uitable(app.Panel_DatosTitulacion);
            app.DatosTitulacion.ColumnName = {'Tiempo (min)'; 'Volumen NaOH (mL)'; 'Temperatura (°C)'};
            app.DatosTitulacion.RowName = {};
            app.TabGroupEquilibrio = uitabgroup(app.DATOS_TAB);
            app.EquilibrioTab = uitab(app.TabGroupEquilibrio);
            app.EquilibrioTab.Title = 'Equilibrio';
            app.ConstantedeHenrykPaLabel = uilabel(app.EquilibrioTab);
            app.ConstantedeHenrykPaLabel.Text = 'Constante de Henry (kPa)';
            app.MolesenelEquilibriomolminLabel = uilabel(app.EquilibrioTab);
            app.MolesenelEquilibriomolminLabel.Text = 'Moles en Equilibrio ';
            app.Display_moles_equilibrio = uieditfield(app.EquilibrioTab, 'numeric');
            app.Display_moles_equilibrio.Editable = 'off';
            app.Display_Constante_Henry = uieditfield(app.EquilibrioTab, 'numeric');
            app.Display_Constante_Henry.Editable = 'off';
            app.TabGroupTransferencia = uitab(app.TabGroupEquilibrio);
            app.TabGroupTransferencia.Title = 'Transferencia';
            app.kLa1sLabel = uilabel(app.TabGroupTransferencia);
            app.kLa1sLabel.Text = 'kLa (1/s)';
            app.deAbsorcinTericoEditFieldLabel = uilabel(app.TabGroupTransferencia);
            app.deAbsorcinTericoEditFieldLabel.Text = '% de Absorción Teórico';
            app.TextoPercetAbs = uieditfield(app.TabGroupTransferencia, 'numeric');
            app.TextoPercetAbs.Editable = 'off';
            app.molestericosabsorbidosLabel = uilabel(app.TabGroupTransferencia);
            app.molestericosabsorbidosLabel.Text = 'Moles Teór. Absorbidos';
            app.TextoMolesTransferencia = uieditfield(app.TabGroupTransferencia, 'numeric');
            app.TextoMolesTransferencia.Editable = 'off';
            app.TextokLa = uieditfield(app.TabGroupTransferencia, 'numeric');
            app.TextokLa.Editable = 'off';
            app.Seccion_Empaquetado = uibuttongroup(app.DATOS_TAB);
            app.Seccion_Empaquetado.Title = 'TIPO DE EMPAQUETADO';
            app.Drop_Down_Diametros = uidropdown(app.Seccion_Empaquetado);
            app.Drop_Down_Diametros.Items = {'N/A'};
            app.Drop_Down_Diametros.Value = 'N/A';
            app.Drop_Down_Empaquetado = uidropdown(app.Seccion_Empaquetado);
            app.Drop_Down_Empaquetado.Items = {'Predeterminado', 'Sillas de Montar', 'Anillos de Raschig'};
            app.Drop_Down_Empaquetado.ValueChangedFcn = createCallbackFcn(app, @Drop_Down_EmpaquetadoValueChanged, true);
            app.Drop_Down_Empaquetado.Value = 'Predeterminado';
            app.DimetroLabel = uilabel(app.Seccion_Empaquetado);
            app.DimetroLabel.Text = 'Diámetro';
            app.Condiciones_operacion = uipanel(app.DATOS_TAB);
            app.Condiciones_operacion.Title = 'CONDICIONES DE OPERACIÓN';
            app.TEMPERATURACEditFieldLabel_2 = uilabel(app.Condiciones_operacion);
            app.TEMPERATURACEditFieldLabel_2.Text = 'TEMPERATURA (°C)';
            app.CAUDALDELIQUIDOLabel = uilabel(app.Condiciones_operacion);
            app.CAUDALDELIQUIDOLabel.Text = 'CAUDAL DE LIQUIDO (L/min) ';
            app.CAUDALDEGASLabel = uilabel(app.Condiciones_operacion);
            app.CAUDALDEGASLabel.Text = 'CAUDAL DE GAS  (L/min)';
            app.display_gas = uieditfield(app.Condiciones_operacion, 'numeric');
            app.display_gas.Limits = [0 10];
            app.display_gas.ValueChangedFcn = createCallbackFcn(app, @display_gasValueChanged, true);
            app.display_liquido = uieditfield(app.Condiciones_operacion, 'numeric');
            app.display_liquido.Limits = [0 10];
            app.display_liquido.ValueChangedFcn = createCallbackFcn(app, @display_liquidoValueChanged, true);
            app.display_temperatura = uieditfield(app.Condiciones_operacion, 'numeric');
            app.display_temperatura.Limits = [0 30];
            app.display_temperatura.ValueChangedFcn = createCallbackFcn(app, @display_temperaturaValueChanged, true);
            app.CAUDALDEGASLabel_2 = uilabel(app.Condiciones_operacion);
            app.CAUDALDEGASLabel_2.Text = 'PRESIÓN DE OPERACIÓN';
            app.display_Presion = uieditfield(app.Condiciones_operacion, 'numeric');
            app.display_Presion.Limits = [0 20];
            app.display_Presion.ValueChangedFcn = createCallbackFcn(app, @display_PresionValueChanged, true);
            app.atmLabel_2 = uilabel(app.Condiciones_operacion);
            app.atmLabel_2.Text = '(atm)';
            app.Boton_Calculos_Teoricos = uibutton(app.DATOS_TAB, 'push');
            app.Boton_Calculos_Teoricos.ButtonPushedFcn = createCallbackFcn(app, @Boton_Calculos_TeoricosButtonPushed, true);
            app.Boton_Calculos_Teoricos.Enable = 'off';
            app.Boton_Calculos_Teoricos.Text = 'CÁLCULOS TEÓRICOS';
            app.Edit_Altura = uieditfield(app.DATOS_TAB, 'numeric');
            app.Edit_Altura.Limits = [0 Inf];
            app.Edit_Altura.Value = 2.5;
            app.Knob_Temperatura = uiknob(app.DATOS_TAB, 'continuous');
            app.Knob_Temperatura.Limits = [0 30];
            app.Knob_Temperatura.ValueChangingFcn = createCallbackFcn(app, @Knob_TemperaturaValueChanging, true);
            app.Knob_Temperatura.Value = 10;
            app.Knob_Caudal_Liquido = uiknob(app.DATOS_TAB, 'continuous');
            app.Knob_Caudal_Liquido.Limits = [0 10];
            app.Knob_Caudal_Liquido.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Knob_Caudal_Liquido.ValueChangingFcn = createCallbackFcn(app, @Knob_Caudal_LiquidoValueChanging, true);
            app.Knob_Caudal_Liquido.MinorTicks = [0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10];
            app.Knob_Caudal_Gas = uiknob(app.DATOS_TAB, 'continuous');
            app.Knob_Caudal_Gas.Limits = [0 10];
            app.Knob_Caudal_Gas.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Knob_Caudal_Gas.ValueChangingFcn = createCallbackFcn(app, @Knob_Caudal_GasValueChanging, true);
            app.Etiqueta_Temperatura = uilabel(app.DATOS_TAB);
            app.Etiqueta_Temperatura.Text = 'TEMPERATURA';
            app.Etiqueta_Caudal_Liquido = uilabel(app.DATOS_TAB);
            app.Etiqueta_Caudal_Liquido.Text = 'CAUDAL DE LIQUIDO ';
            app.Etiqueta_Caudal_Gas = uilabel(app.DATOS_TAB);
            app.Etiqueta_Caudal_Gas.Text = 'CAUDAL DE GAS';
            app.PresinSliderLabel = uilabel(app.DATOS_TAB);
            app.PresinSliderLabel.Text = 'Presión';
            app.Slider_Presion = uislider(app.DATOS_TAB);
            app.Slider_Presion.Limits = [0 2];
            app.Slider_Presion.ValueChangingFcn = createCallbackFcn(app, @Slider_PresionValueChanging, true);
            app.CLabel = uilabel(app.DATOS_TAB);
            app.CLabel.Text = '(°C)';
            app.LminLabel = uilabel(app.DATOS_TAB);
            app.LminLabel.Text = '(L/min)';
            app.atmLabel = uilabel(app.DATOS_TAB);
            app.atmLabel.Text = '(atm)';
            app.LminLabel_2 = uilabel(app.DATOS_TAB);
            app.LminLabel_2.Text = '(L/min)';
            app.AlturadeempaqueLabel = uilabel(app.DATOS_TAB);
            app.AlturadeempaqueLabel.Text = 'Altura de empaque';
            app.mLabel = uilabel(app.DATOS_TAB);
            app.mLabel.Text = '(m)';
            app.RESULTADOSTab = uitab(app.TabGroup);
            app.RESULTADOSTab.Title = 'RESULTADOS';
            app.GraficoMolesTitulacion = uiaxes(app.RESULTADOSTab);
            title(app.GraficoMolesTitulacion, 'Moles de CO2 Absorbidos')
            xlabel(app.GraficoMolesTitulacion, 'Tiempo (min)')
            ylabel(app.GraficoMolesTitulacion, 'Moles de CO2 (mol/min)')
            zlabel(app.GraficoMolesTitulacion, 'Z')
            app.GraficoAbsorcionTitulacion = uiaxes(app.RESULTADOSTab);
            title(app.GraficoAbsorcionTitulacion, 'Porcentaje de Absorción')
            xlabel(app.GraficoAbsorcionTitulacion, 'Tiempo (min)')
            ylabel(app.GraficoAbsorcionTitulacion, '% de Absorción')
            zlabel(app.GraficoAbsorcionTitulacion, 'Z')
            app.RESULTADOSLabel = uilabel(app.RESULTADOSTab);
            app.RESULTADOSLabel.Text = 'RESULTADOS';
            app.ResultadosTitulacinPanel = uipanel(app.RESULTADOSTab);
            app.ResultadosTitulacinPanel.Title = 'Resultados - Titulación';
            app.deAbsLabel = uilabel(app.ResultadosTitulacinPanel);
            app.deAbsLabel.Text = '% de Abs.';
            app.ExportaraExcelButton = uibutton(app.ResultadosTitulacinPanel, 'push');
            app.ExportaraExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportaraExcelButtonPushed, true);
            app.ExportaraExcelButton.Text = 'Exportar a Excel';
            app.Display_eficiencia = uieditfield(app.ResultadosTitulacinPanel, 'numeric');
            app.Display_eficiencia.Editable = 'off';
            app.MolesAbsLabel = uilabel(app.ResultadosTitulacinPanel);
            app.MolesAbsLabel.Text = 'Moles Abs.';
            app.PercentAbsTitu = uieditfield(app.ResultadosTitulacinPanel, 'numeric');
            app.PercentAbsTitu.Editable = 'off';
            app.MolesAbsTitu = uieditfield(app.ResultadosTitulacinPanel, 'numeric');
            app.MolesAbsTitu.Editable = 'off';
            app.MolesAbsEditField_2Label_2 = uilabel(app.ResultadosTitulacinPanel);
            app.MolesAbsEditField_2Label_2.Text = 'Moles de CO2';
            app.Display_Moles_Totales = uieditfield(app.ResultadosTitulacinPanel, 'numeric');
            app.Display_Moles_Totales.Editable = 'off';
            app.MolesAbsEditField_2Label_3 = uilabel(app.ResultadosTitulacinPanel);
            app.MolesAbsEditField_2Label_3.Text = 'Eficiencia';
            app.ResultadosTitulacion = uitable(app.RESULTADOSTab);
            app.ResultadosTitulacion.ColumnName = {'Concentración de CO2 (M)'; 'Moles Absorbidos'; '% de Absorción'};
            app.ResultadosTitulacion.RowName = {};
            app.FLUJOSTab = uitab(app.TabGroup);
            app.FLUJOSTab.Title = 'FLUJOS';
            app.Image2 = uiimage(app.FLUJOSTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'diagrama.png');
            app.FlujoGoutmolminLabel = uilabel(app.FLUJOSTab);
            app.FlujoGoutmolminLabel.Text = 'Flujo - G out (mol/min)';
            app.Edit_SG = uieditfield(app.FLUJOSTab, 'numeric');
            app.Edit_SG.Editable = 'off';
            app.FraccinMolarCO2Label_3 = uilabel(app.FLUJOSTab);
            app.FraccinMolarCO2Label_3.Text = 'Fracción Molar CO2';
            app.Edit_Fraccion_SG = uieditfield(app.FLUJOSTab, 'numeric');
            app.Edit_Fraccion_SG.Editable = 'off';
            app.FlujoGinmolminLabel = uilabel(app.FLUJOSTab);
            app.FlujoGinmolminLabel.Text = 'Flujo - G in (mol/min)';
            app.Edit_EG = uieditfield(app.FLUJOSTab, 'numeric');
            app.Edit_EG.Editable = 'off';
            app.FraccinMolarCO2Label_4 = uilabel(app.FLUJOSTab);
            app.FraccinMolarCO2Label_4.Text = 'Fracción Molar CO2';
            app.Edit_Fraccion_EG = uieditfield(app.FLUJOSTab, 'numeric');
            app.Edit_Fraccion_EG.Editable = 'off';
            app.Edit_Fraccion_EG.Value = 1;
            app.FlujoLoutmolminLabel = uilabel(app.FLUJOSTab);
            app.FlujoLoutmolminLabel.Text = 'Flujo - L out (mol/min)';
            app.Edit_SL = uieditfield(app.FLUJOSTab, 'numeric');
            app.Edit_SL.Editable = 'off';
            app.FraccinMolarCO2Label = uilabel(app.FLUJOSTab);
            app.FraccinMolarCO2Label.Text = 'Fracción Molar CO2';
            app.Edit_Fraccion_SL = uieditfield(app.FLUJOSTab, 'numeric');
            app.Edit_Fraccion_SL.Editable = 'off';
            app.FlujoLinmolminLabel = uilabel(app.FLUJOSTab);
            app.FlujoLinmolminLabel.Text = 'Flujo - L in (mol/min)';
            app.Edit_EL = uieditfield(app.FLUJOSTab, 'numeric');
            app.Edit_EL.Editable = 'off';
            app.FraccinMolarCO2Label_2 = uilabel(app.FLUJOSTab);
            app.FraccinMolarCO2Label_2.Text = 'Fracción Molar CO2';
            app.Edit_Fraccion_EL = uieditfield(app.FLUJOSTab, 'numeric');
            app.Edit_Fraccion_EL.Editable = 'off';
            app.Titulo_Datos_Teoricos_Experimentales_2 = uilabel(app.FLUJOSTab);
            app.Titulo_Datos_Teoricos_Experimentales_2.Text = 'FLUJOS Y FRACCIONES MOLARES';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = absorcion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end